classdef BeatDetectionDlg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PreviewSizeSecondsEditField     matlab.ui.control.EditField
        PreviewSizeSecondsEditFieldLabel  matlab.ui.control.Label
        XlimSlider                      matlab.ui.control.Slider
        XlimSliderLabel                 matlab.ui.control.Label
        RunButton_2                     matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
        HeartRateCalculationSettingsPanel  matlab.ui.container.Panel
        ConvolutionWindowSizeEditField  matlab.ui.control.EditField
        ConvolutionWindowSizeEditFieldLabel  matlab.ui.control.Label
        BeatDetectionSettingsPanel      matlab.ui.container.Panel
        ThresholdPercentileEditField    matlab.ui.control.EditField
        ThresholdPercentileEditFieldLabel  matlab.ui.control.Label
        UseAbsoluteValueCheckBox        matlab.ui.control.CheckBox
        ThresholdWindowsecEditField     matlab.ui.control.EditField
        ThresholdWindowsecLabel         matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %% Main Objects
        TonaFlowApp % Calling app
        ECG % The main ECG object, which we get from the calling app
        %% Plot Objects
        ECG_Plot 
        Threshold_Plot

        XLIM_H = 1;
        prevwin = 1;
        thresholds % Description
    end
    
    methods (Access = public)
        
        
    end
    
    methods (Access = private)
        
        function plotPreview(app)
            % Check if the plot object is empty, if it is then create it
            if isempty(app.ECG_Plot)
                app.ECG_Plot = plot(app.UIAxes,app.ECG.X_Filtered, app.ECG.Y_Filtered);
                app.Threshold_Plot = plot(app.UIAxes,app.ECG.X_Filtered,app.thresholds,'r--');
            else
                app.Threshold_Plot.XData = app.ECG.X_Filtered;
                app.Threshold_Plot.YData = app.thresholds;
            end

            
            % plot(app.UIAxes,app.ECG.X_Filtered,app.thresholds,'r--');
            ylim(app.UIAxes,[min(app.ECG.Y_Filtered) max(app.ECG.Y_Filtered)])
            xlim(app.UIAxes, [app.XLIM_H - app.prevwin app.XLIM_H])
            % cla(app.UIAxes);
            % plot(app.UIAxes,app.ECG.X_Filtered,app.ECG.Y_Filtered);
            % hold(app.UIAxes,'on');
            % plot(app.UIAxes,app.ECG.X_Filtered,app.thresholds,'r--');
            % 
            % ylim(app.UIAxes,[min(app.ECG.Y_Filtered) max(app.ECG.Y_Filtered)])
            % xlim(app.UIAxes, [app.XLIM_H - app.prevwin app.XLIM_H])
            % 
            % set(app.UIAxes, 'XTickMode', 'auto', 'XTickLabelMode', 'auto')
            % xticks(app.UIAxes,0:.5:app.ECG.X_Filtered(end));
            % 
            app.XlimSlider.Limits = [0 app.ECG.X_Filtered(end)];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            hold(app.UIAxes,'on');

            app.TonaFlowApp = mainapp;
            % Grab the main ECG object from tonaflow
            app.ECG = mainapp.ECG;
            app.thresholds = PreviewThreshold(app.ECG.X_Filtered, app.ECG.Y_Filtered, 97.5, app.ECG.Fs, str2num(app.ThresholdWindowsecEditField.Value), str2num(app.ThresholdPercentileEditField.Value));
            % app.thresholds = PreviewThreshold(mainapp.ECG_X, mainapp.ECG_Y, 97.5,mainapp.CalculatedFs,str2num(app.ThresholdWindowsecEditField.Value));
            app.plotPreview;
        end

        % Button pushed function: RunButton_2
        function RunButton_2Pushed(app, event)
            waitdlg = uiprogressdlg(app.TonaFlowApp.UIFigure,'Message',"Calculating Heart Rate...");
            % Calculate the heart beats
            if app.UseAbsoluteValueCheckBox.Value == 1
                yfilt = abs(app.ECG.Y_Filtered);
            else
                yfilt = app.ECG.Y_Filtered;
            end
            app.ECG = app.ECG.CalculateBeats(app.ECG.X_Filtered,yfilt,app.ECG.Fs, str2num(app.ThresholdWindowsecEditField.Value),str2num(app.ThresholdPercentileEditField.Value));
            waitdlg.Value = .25;
            % And now the heart rate, using the beats
            app.ECG = app.ECG.CalculateHeartRate(app.ECG.Beats,app.ECG.X_Filtered,app.ECG.Fs,str2num(app.ConvolutionWindowSizeEditField.Value)); 
            waitdlg.Value = .50;
            %% Assign new data to object
            app.ECG.Thresholds = app.thresholds;
            % app.ECG.Beats = beats;
            % app.ECG.HeartRate = rate;

            app.TonaFlowApp.ECG = app.ECG; %Assign it to TonaFlow
            
            % Change the flags in TonaFlow to signal that the algorithm has
            % been run 
            app.TonaFlowApp.HasBeatRun = 1;
            % Access the replot function inside TonaFlow
            app.TonaFlowApp.ReplotECG;
            
            close(waitdlg)
            %Close the app
            delete(app);
        end

        % Value changing function: XlimSlider
        function XlimSliderValueChanging(app, event)
            app.XLIM_H = event.Value;
            xlim(app.UIAxes,[app.XLIM_H - app.prevwin app.XLIM_H])
        end

        % Value changing function: PreviewSizeSecondsEditField
        function PreviewSizeSecondsEditFieldValueChanging(app, event)
            app.prevwin = str2num(event.Value);
            xlim(app.UIAxes, [app.XLIM_H - app.prevwin app.XLIM_H])
        end

        % Value changed function: ThresholdWindowsecEditField
        function ThresholdWindowsecEditFieldValueChanged(app, event)
            value = app.ThresholdWindowsecEditField.Value;
            app.thresholds = PreviewThreshold(app.ECG.X_Filtered, app.ECG.Y_Filtered, 97.5,app.ECG.Fs,str2num(value),str2num(app.ThresholdPercentileEditField.Value));
            app.plotPreview;
        end

        % Value changed function: ConvolutionWindowSizeEditField
        function ConvolutionWindowSizeEditFieldValueChanged(app, event)

        end

        % Value changed function: UseAbsoluteValueCheckBox
        function UseAbsoluteValueCheckBoxValueChanged(app, event)
            value = app.UseAbsoluteValueCheckBox.Value;
            if value == 1
                app.ECG_Plot.YData = abs(app.ECG.Y_Filtered);
            elseif value == 0 
                app.ECG_Plot.YData = app.ECG.Y_Filtered;
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app)
        end

        % Value changed function: ThresholdPercentileEditField
        function ThresholdPercentileEditFieldValueChanged(app, event)
            value = app.ThresholdPercentileEditField.Value;
            app.thresholds = PreviewThreshold(app.ECG.X_Filtered, app.ECG.Y_Filtered, 97.5,app.ECG.Fs,str2num(app.ThresholdWindowsecEditField.Value),str2num(value));
            app.plotPreview;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Preview')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.BeatDetectionSettingsPanel = uipanel(app.UIFigure);
            app.BeatDetectionSettingsPanel.Title = 'Beat Detection Settings...';
            app.ThresholdWindowsecLabel = uilabel(app.BeatDetectionSettingsPanel);
            app.ThresholdWindowsecLabel.Text = 'Threshold Window (sec) ';
            app.ThresholdWindowsecEditField = uieditfield(app.BeatDetectionSettingsPanel, 'text');
            app.ThresholdWindowsecEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdWindowsecEditFieldValueChanged, true);
            app.ThresholdWindowsecEditField.Value = '1';
            app.UseAbsoluteValueCheckBox = uicheckbox(app.BeatDetectionSettingsPanel);
            app.UseAbsoluteValueCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseAbsoluteValueCheckBoxValueChanged, true);
            app.UseAbsoluteValueCheckBox.Text = 'Use Absolute Value';
            app.ThresholdPercentileEditFieldLabel = uilabel(app.BeatDetectionSettingsPanel);
            app.ThresholdPercentileEditFieldLabel.Text = 'Threshold Percentile';
            app.ThresholdPercentileEditField = uieditfield(app.BeatDetectionSettingsPanel, 'text');
            app.ThresholdPercentileEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdPercentileEditFieldValueChanged, true);
            app.ThresholdPercentileEditField.Value = '97.5';
            app.HeartRateCalculationSettingsPanel = uipanel(app.UIFigure);
            app.HeartRateCalculationSettingsPanel.Title = 'Heart Rate Calculation Settings...';
            app.ConvolutionWindowSizeEditFieldLabel = uilabel(app.HeartRateCalculationSettingsPanel);
            app.ConvolutionWindowSizeEditFieldLabel.Text = 'Convolution Window Size';
            app.ConvolutionWindowSizeEditField = uieditfield(app.HeartRateCalculationSettingsPanel, 'text');
            app.ConvolutionWindowSizeEditField.ValueChangedFcn = createCallbackFcn(app, @ConvolutionWindowSizeEditFieldValueChanged, true);
            app.ConvolutionWindowSizeEditField.Value = '10';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.RunButton_2 = uibutton(app.UIFigure, 'push');
            app.RunButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunButton_2Pushed, true);
            app.RunButton_2.Text = 'Run';
            app.XlimSliderLabel = uilabel(app.UIFigure);
            app.XlimSliderLabel.Text = 'Xlim';
            app.XlimSlider = uislider(app.UIFigure);
            app.XlimSlider.MajorTicks = [];
            app.XlimSlider.ValueChangingFcn = createCallbackFcn(app, @XlimSliderValueChanging, true);
            app.PreviewSizeSecondsEditFieldLabel = uilabel(app.UIFigure);
            app.PreviewSizeSecondsEditFieldLabel.Text = 'Preview Size (Seconds)';
            app.PreviewSizeSecondsEditField = uieditfield(app.UIFigure, 'text');
            app.PreviewSizeSecondsEditField.InputType = 'digits';
            app.PreviewSizeSecondsEditField.ValueChangingFcn = createCallbackFcn(app, @PreviewSizeSecondsEditFieldValueChanging, true);
            app.PreviewSizeSecondsEditField.Value = '1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BeatDetectionDlg(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end