classdef CWTBandpassApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CWTBandpassUIFigure           matlab.ui.Figure
        PreviewPanel                  matlab.ui.container.Panel
        PreviewWindowSizeEditField    matlab.ui.control.EditField
        PreviewWindowSizeEditFieldLabel  matlab.ui.control.Label
        FrequencyAdjustmentPanel      matlab.ui.container.Panel
        LowerFrequencyEditField       matlab.ui.control.EditField
        LowerFrequencyEditFieldLabel  matlab.ui.control.Label
        UpperFrequencyEditField       matlab.ui.control.EditField
        UpperFrequencyEditFieldLabel  matlab.ui.control.Label
        FilterButton                  matlab.ui.control.Button
        CancelButton                  matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        TonaFlowApp 
        ECG
        ECG_Plot
        ECGcomp_Plot
        ECGmean % We add this to the comp so that it resembles the original Y values of the unfiltered data

        filteredECG_Y % Changes dynamically
        comp % Composite CWT
        cfs
        f
        bounds
        ECG_Y
        CWTlims 

    end
    
    methods (Access = private)
        
        function RecalculateCWT(app)
            app.comp=icwt(app.cfs,[],app.f,app.bounds);
            app.comp = app.comp + app.ECGmean;
            app.UIAxes.Children(1).YData = app.comp;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Initializations
            app.TonaFlowApp = mainapp;
            app.ECG = app.TonaFlowApp.ECG;
            app.ECGmean = mean(app.ECG.Y_Filtered);
            % Plot the signal
            app.ECG_Plot = plot(app.UIAxes, app.ECG.X_Filtered, app.ECG.Y_Filtered,'color',[0 0.4470 0.7410 .4]);
            hold(app.UIAxes,'on');
            % Calculate the CWT
            [app.cfs app.f] = cwt(app.ECG.Y_Filtered, 'bump',150, 'VoicesPerOctive',32);
            app.bounds = [min(app.f) max(app.f)];
            app.CWTlims = app.bounds; % This variable is for checking whether the user enters lims out of bounds
            app.comp=icwt(app.cfs,[],app.f,app.bounds);
            app.comp = app.comp + app.ECGmean;

            % Plot the composite 
            app.ECGcomp_Plot = plot(app.UIAxes, app.ECG.X_Filtered, app.comp,'Color',[0.8500 0.3250 0.0980],'LineWidth',3);
            % Fill in the bounds boxes with their respectives 
            app.UpperFrequencyEditField.Value = string(app.bounds(2));
            app.LowerFrequencyEditField.Value = string(app.bounds(1));

            % app.ECG_Y = app.TonaFlowApp.ECG_Y - mean(app.TonaFlowApp.ECG_Y);


            % plot(app.UIAxes, app.TonaFlowApp.ECG_X, app.ECG_Y,"Color",[0 0.4470 0.7410 .4])
            % hold(app.UIAxes,'on')

            % Calculate CWT
            % [app.cfs, app.f] = cwt(app.ECG_Y,'bump',150,'VoicesPerOctive',32);
            % app.bounds = [min(app.f) max(app.f)];
            % app.CWTlims = app.bounds; % This variable is for checking whether the user enters lims out of bounds
            % app.comp=icwt(app.cfs,[],app.f,app.bounds);
            
            % plot(app.UIAxes, app.TonaFlowApp.ECG_X, app.comp,'Color',[0.8500 0.3250 0.0980],'LineWidth',3)

            % % Fill in the bounds boxes with their respectives 
            % app.UpperFrequencyEditField.Value = string(app.bounds(2));
            % app.LowerFrequencyEditField.Value = string(app.bounds(1));
            
        end

        % Value changing function: UpperFrequencyEditField
        function UpperFrequencyEditFieldValueChanging(app, event)
            changingValue = event.Value;

        end

        % Value changed function: UpperFrequencyEditField
        function UpperFrequencyEditFieldValueChanged(app, event)
            upperBound = str2num(app.UpperFrequencyEditField.Value);
            % Let's first check if the value is outside of the bounds or if
            % it is less than the lower value
            if upperBound <= app.CWTlims(1) | upperBound > app.CWTlims(2)
                upperBound = app.CWTlims(2); %reset it
                app.UpperFrequencyEditField.Value = string(app.CWTlims(2)); % Reset the value box        
            end
            app.bounds(2) = upperBound;
            % Recalculate CWT
            app.RecalculateCWT;
        end

        % Value changed function: LowerFrequencyEditField
        function LowerFrequencyEditFieldValueChanged(app, event)
            lowerBound = str2num(app.LowerFrequencyEditField.Value);
            % Let's first check if the value is outside of the bounds or if
            % it is less than the lower value
            if lowerBound <= app.CWTlims(1) | lowerBound > app.CWTlims(2)
                lowerBound = app.CWTlims(1); %reset it
                app.LowerFrequencyEditField.Value = string(app.CWTlims(1)); % Reset the value box
            end
            app.bounds(1) = lowerBound;
            % Recalculate CWT
            app.RecalculateCWT;
        end

        % Button pushed function: FilterButton
        function FilterButtonPushed(app, event)
            % app.TonaFlowApp.ECG_Y = app.comp;
            % app.TonaFlowApp.UIAxes.Children(1).YData = app.comp;
            % delete(app)
            app.ECG.Y_Filtered = app.comp;
            % Reassign object to tonaflow
            app.TonaFlowApp.ECG = app.ECG;
            app.TonaFlowApp.ReplotECG;

            % Now that the ECG has been filtered, run the function that
            % will enable other things in TonaFlow.
            app.TonaFlowApp.EnableAfterFilter;
            delete(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CWTBandpassUIFigure = uifigure('Visible', 'off');
            app.CWTBandpassUIFigure.Name = 'CWT Bandpass';
            app.UIAxes = uiaxes(app.CWTBandpassUIFigure);
            title(app.UIAxes, 'Preview')
            xlabel(app.UIAxes, 'X')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YTick = [];
            app.CancelButton = uibutton(app.CWTBandpassUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.FilterButton = uibutton(app.CWTBandpassUIFigure, 'push');
            app.FilterButton.ButtonPushedFcn = createCallbackFcn(app, @FilterButtonPushed, true);
            app.FilterButton.Text = 'Filter';
            app.FrequencyAdjustmentPanel = uipanel(app.CWTBandpassUIFigure);
            app.FrequencyAdjustmentPanel.Title = 'Frequency Adjustment';
            app.UpperFrequencyEditFieldLabel = uilabel(app.FrequencyAdjustmentPanel);
            app.UpperFrequencyEditFieldLabel.Text = 'Upper Frequency';
            app.UpperFrequencyEditField = uieditfield(app.FrequencyAdjustmentPanel, 'text');
            app.UpperFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @UpperFrequencyEditFieldValueChanged, true);
            app.UpperFrequencyEditField.ValueChangingFcn = createCallbackFcn(app, @UpperFrequencyEditFieldValueChanging, true);
            app.LowerFrequencyEditFieldLabel = uilabel(app.FrequencyAdjustmentPanel);
            app.LowerFrequencyEditFieldLabel.Text = 'Lower Frequency';
            app.LowerFrequencyEditField = uieditfield(app.FrequencyAdjustmentPanel, 'text');
            app.LowerFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @LowerFrequencyEditFieldValueChanged, true);
            app.PreviewPanel = uipanel(app.CWTBandpassUIFigure);
            app.PreviewPanel.Title = 'Preview';
            app.PreviewPanel.Visible = 'off';
            app.PreviewWindowSizeEditFieldLabel = uilabel(app.PreviewPanel);
            app.PreviewWindowSizeEditFieldLabel.Text = 'Preview Window Size';
            app.PreviewWindowSizeEditField = uieditfield(app.PreviewPanel, 'text');
            % Show the figure after all components are created
            app.CWTBandpassUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CWTBandpassApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CWTBandpassUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CWTBandpassUIFigure)
        end
    end
end