classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        LoadECGMenu                   matlab.ui.container.Menu
        SaveMenu                      matlab.ui.container.Menu
        AllContentsMenu               matlab.ui.container.Menu
        BeatsMenu                     matlab.ui.container.Menu
        ECGMenu_2                     matlab.ui.container.Menu
        RespiratorySignalMenu         matlab.ui.container.Menu
        ECGMenu                       matlab.ui.container.Menu
        FilterwithCWTMenu             matlab.ui.container.Menu
        ExtractRespiratorySignalMenu  matlab.ui.container.Menu
        CalculateHeartRateMenu        matlab.ui.container.Menu
        DataRemovalPanel              matlab.ui.container.Panel
        SelectDataforRemovalButton    matlab.ui.control.Button
        EnterRemovalModeButton        matlab.ui.control.StateButton
        Ver08Label                    matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        FileDetailsPanel              matlab.ui.container.Panel
        SamplingRateLabel             matlab.ui.control.Label
        FileNameLabel                 matlab.ui.control.Label
        ViewPanel                     matlab.ui.container.Panel
        ShowRawECGCheckBox            matlab.ui.control.CheckBox
        ShowFilteredECGCheckBox       matlab.ui.control.CheckBox
        UpperXLimitEditField          matlab.ui.control.EditField
        UpperXLimitEditFieldLabel     matlab.ui.control.Label
        LowerXLimitEditField          matlab.ui.control.EditField
        LowerXLimitEditFieldLabel     matlab.ui.control.Label
        ECGPanel                      matlab.ui.container.Panel
        RemoveHeartbeatsButton        matlab.ui.control.Button
        AddHeartbeatsButton           matlab.ui.control.Button
        RespRate_Axis                 matlab.ui.control.UIAxes
        HeartRate_Axis                matlab.ui.control.UIAxes
        ECG_Axis                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %% Data removal properties
        RemovalRects = {} % These are where we store the rectangle objects (image.roi.rectangle class) 
        %% Plot objects. We have these so that we can just edit the X/Y Data!
        ECG_Plot
        ECGRaw_Plot
        Beat_Plot
        HeartRate_Plot
        RespRate_Plot


        %% Default colors
        ECG_Settings = {'Color',[.1 .6 .6],'LineWidth',1.5};
        ECG_SettingsRaw = {'Color',[.8 .2 .9 .1],'LineWidth',1.5};
        HR_Settings = {'Color',[.3 .1 .9],'LineWidth',1.5};
        Resp_Color = {'Color','b','LineWidth',1.5};
        Beat_Settings = {'Color',[.4 .3 .5],'MarkerSize',6,'LineStyle','None','Marker','o'};

        RemovalActivatedText
    end
    
    properties (Access = public)
        %% Main ECG object. This will act as a master object. Properties are within this.
        ECG 
        %% Flags
        HasBeatRun = 0% Has the beat detection/HR run?
    end
    
    methods (Access = public)
        %% A method to plot/replot the ECG data
        function ReplotECG(app)
            
            % Check if the data is spliced
            if ~app.ECG.IsSpliced
                app.ECG_Plot.XData = app.ECG.X_Filtered;
                app.ECG_Plot.YData = app.ECG.Y_Filtered;
            else
                app.ECG_Plot.XData = app.ECG.X_Spliced;
                app.ECG_Plot.YData = app.ECG.Y_Spliced;
            end

            % Replot beats, but make sure they exist first.
            if ~isempty(app.Beat_Plot)

                if ~app.ECG.IsSpliced
                    app.Beat_Plot.XData = app.ECG.X_Filtered(app.ECG.Beats == 1);
                    app.Beat_Plot.YData = app.ECG.Y_Filtered(app.ECG.Beats == 1);
                else
                    app.Beat_Plot.XData = app.ECG.X_Spliced(app.ECG.BeatsSpliced == 1);
                    app.Beat_Plot.YData = app.ECG.Y_Spliced(app.ECG.BeatsSpliced == 1);
                end
            elseif app.HasBeatRun % Doesn't exist, plot it
                app.Beat_Plot = plot(app.ECG_Axis,app.ECG.X_Filtered(app.ECG.Beats == 1),app.ECG.Y_Filtered(app.ECG.Beats == 1),app.Beat_Settings{:});
            end

            % Same thing with the heart rate
            if ~isempty(app.HeartRate_Plot)
                if ~app.ECG.IsSpliced
                    app.HeartRate_Plot.XData = app.ECG.X_Filtered;
                    app.HeartRate_Plot.YData = app.ECG.HeartRate;
                else
                    app.HeartRate_Plot.XData = app.ECG.X_Spliced;
                    app.HeartRate_Plot.YData = app.ECG.HeartRate;
                end
            elseif app.HasBeatRun
                app.HeartRate_Plot = plot(app.HeartRate_Axis,app.ECG.X_Filtered,app.ECG.HeartRate,app.HR_Settings{:});
            end
            
        end
        
        function EnableAfterFilter(app)
            app.ShowFilteredECGCheckBox.Enable = 1;
            app.ShowRawECGCheckBox.Enable = 1;
            app.ECGRaw_Plot = plot(app.ECG_Axis,app.ECG.X_Raw, app.ECG.Y_Raw, app.ECG_SettingsRaw{:});
            legend(app.ECG_Axis,{'Filtered','Raw'})
        end
    end
    
    methods (Access = private)
        
        function OnMove(app,src,evt)
            evname = evt.EventName;
            switch evname
                case "MovingROI"
                    src.Position(end) = max(app.ECG.Y_Filtered) * 2;
                    src.Position(2) = min(app.ECG.Y_Filtered);
            end
        end
        
        function drawRectDetectionFcn(app,src,evt)
                axChil = app.ECG_Axis.Children; 
                chilClass = arrayfun(@(h){class(h)},axChil);
                isRect = strcmpi(chilClass, 'images.roi.Rectangle');
                isRect = isRect(1); % Check if the first entry is a rectangle
                if isRect
                    if ~isempty(axChil(1).Position)
                        % Set the position to be within the bounds of the
                        % ECG
                        axChil(1).Position(end) = max(app.ECG.Y_Filtered) * 2;
                        axChil(1).Position(2) = min(app.ECG.Y_Filtered);
                    end
                end     
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            hold(app.ECG_Axis,'on'); 
            linkaxes([app.ECG_Axis, app.HeartRate_Axis, app.RespRate_Axis],'x')
            % Start the event listener for the ECG Axes
            app.ECG_Axis.UserData.Listener = addlistener(app.ECG_Axis,'ChildAdded',@app.drawRectDetectionFcn);
        end

        % Menu selected function: LoadECGMenu
        function LoadECGMenuSelected(app, event)
            %% Load the ECG object into the ECG_Class 
            [file,path] = uigetfile("*.csv");
            app.ECG = ECG_Class(strcat(path,file));

            % Plot the ECG in the axes and create the object
            app.ECG_Plot = plot(app.ECG_Axis,app.ECG.X_Filtered,app.ECG.Y_Filtered,app.ECG_Settings{:});
            % Set the axis limits to be within the confines of the data 
            xlim(app.ECG_Axis,[min(app.ECG.X_Filtered) max(app.ECG.X_Filtered)]);
            % Enable things that can now be used now that the user has
            % loaded in an ECGc
        end

        % Menu selected function: CalculateHeartRateMenu
        function CalculateHeartRateMenuSelected(app, event)
            BeatDetectionDlg(app);
        end

        % Value changed function: ShowFilteredECGCheckBox
        function ShowFilteredECGCheckBoxValueChanged(app, event)
            value = app.ShowFilteredECGCheckBox.Value;
            if value == 0
                app.ECG_Plot.Color = [app.ECG_Plot.Color .1];
            elseif value == 1
                app.ECG_Plot.Color = [app.ECG_Plot.Color 1];
            end
        end

        % Menu selected function: FilterwithCWTMenu
        function FilterwithCWTMenuSelected(app, event)
            CWTBandpassApp(app);
        end

        % Value changed function: ShowRawECGCheckBox
        function ShowRawECGCheckBoxValueChanged(app, event)
            value = app.ShowRawECGCheckBox.Value;
            if value == 0
                app.ECGRaw_Plot.Color = [app.ECGRaw_Plot.Color .1];
            elseif value == 1
                app.ECGRaw_Plot.Color = [app.ECGRaw_Plot.Color 1];
            end
        end

        % Button pushed function: SelectDataforRemovalButton
        function SelectDataforRemovalButtonPushed(app, event)
            % Create the rectangle and add the listeners
            rect = drawrectangle(app.ECG_Axis);
            addlistener(rect,"MovingROI",@app.OnMove);
            % Check if the user has canceled the draw
            if ~isempty(rect.Position)
                % Store it in the array
                app.RemovalRects{length(app.RemovalRects)+1} = rect;
            end
        end

        % Value changed function: EnterRemovalModeButton
        function EnterRemovalModeButtonValueChanged(app, event)
            value = app.EnterRemovalModeButton.Value;
            if value == 1
                % Reenable the select data for removal button
                app.SelectDataforRemovalButton.Enable = 1;
                % add text that signals that removal mode has been
                % activated 
                app.RemovalActivatedText = text('Parent',app.ECG_Axis,...
                    'String','REMOVAL MODE ACTIVATED', ...
                    'Units', 'normalized', ...
                    'Position',[.01 .88],...
                    'Color',[1 .1 0],...
                    'FontWeight','bold',...
                    'FontSize',15);
                % Then enable every removal rectangle if any
                if ~isempty(app.RemovalRects)
                    for i = 1:length(app.RemovalRects)
                       app.RemovalRects{i}.InteractionsAllowed = 'all';
                    end
                end
            elseif value == 0
                app.SelectDataforRemovalButton.Enable = 0;
                delete(app.RemovalActivatedText)
                
                % Check if there are rects stored in the removalrects
                % array, if there are then the data is spliced and we can
                % re-run calculations
                RectCheck = cellfun(@isvalid,app.RemovalRects);
                % Delete the entries with invalid handles
                app.RemovalRects = app.RemovalRects(RectCheck);
                if any(RectCheck) % Yes, there are splice rectangles in the plot
                    app.ECG = app.ECG.SpliceECG(app.RemovalRects,app);
                    % Recalculate heart rate 
                    app.ECG = app.ECG.CalculateHeartRate(app.ECG.BeatsSpliced,app.ECG.X_Spliced,app.ECG.Fs);
                    app.ReplotECG;
                end
                % Disable interactions on the rects
                if ~isempty(app.RemovalRects)
                    for i = 1:length(app.RemovalRects)
                       app.RemovalRects{i}.InteractionsAllowed = 'none';
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadECGMenu = uimenu(app.FileMenu);
            app.LoadECGMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadECGMenuSelected, true);
            app.LoadECGMenu.Text = 'Load ECG';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Text = 'Save...';
            app.AllContentsMenu = uimenu(app.SaveMenu);
            app.AllContentsMenu.Text = 'All Contents';
            app.BeatsMenu = uimenu(app.SaveMenu);
            app.BeatsMenu.Text = 'Beats';
            app.ECGMenu_2 = uimenu(app.SaveMenu);
            app.ECGMenu_2.Text = 'ECG';
            app.RespiratorySignalMenu = uimenu(app.SaveMenu);
            app.RespiratorySignalMenu.Text = 'Respiratory Signal';
            app.ECGMenu = uimenu(app.UIFigure);
            app.ECGMenu.Text = 'ECG';
            app.FilterwithCWTMenu = uimenu(app.ECGMenu);
            app.FilterwithCWTMenu.MenuSelectedFcn = createCallbackFcn(app, @FilterwithCWTMenuSelected, true);
            app.FilterwithCWTMenu.Text = 'Filter with CWT';
            app.ExtractRespiratorySignalMenu = uimenu(app.ECGMenu);
            app.ExtractRespiratorySignalMenu.Text = 'Extract Respiratory Signal';
            app.CalculateHeartRateMenu = uimenu(app.ECGMenu);
            app.CalculateHeartRateMenu.MenuSelectedFcn = createCallbackFcn(app, @CalculateHeartRateMenuSelected, true);
            app.CalculateHeartRateMenu.Text = 'Calculate Heart Rate';
            app.ECG_Axis = uiaxes(app.UIFigure);
            title(app.ECG_Axis, 'ECG')
            xlabel(app.ECG_Axis, 'Time')
            ylabel(app.ECG_Axis, 'Y')
            zlabel(app.ECG_Axis, 'Z')
            app.HeartRate_Axis = uiaxes(app.UIFigure);
            title(app.HeartRate_Axis, 'Heart Rate')
            xlabel(app.HeartRate_Axis, 'Time')
            ylabel(app.HeartRate_Axis, 'Y')
            zlabel(app.HeartRate_Axis, 'Z')
            app.RespRate_Axis = uiaxes(app.UIFigure);
            title(app.RespRate_Axis, 'Respiration Rate')
            xlabel(app.RespRate_Axis, 'X')
            ylabel(app.RespRate_Axis, 'Y')
            zlabel(app.RespRate_Axis, 'Z')
            app.ECGPanel = uipanel(app.UIFigure);
            app.ECGPanel.Title = 'ECG';
            app.AddHeartbeatsButton = uibutton(app.ECGPanel, 'push');
            app.AddHeartbeatsButton.Text = 'Add Heartbeat(s)';
            app.RemoveHeartbeatsButton = uibutton(app.ECGPanel, 'push');
            app.RemoveHeartbeatsButton.Text = 'Remove Heartbeat(s)';
            app.ViewPanel = uipanel(app.UIFigure);
            app.ViewPanel.Title = 'View';
            app.LowerXLimitEditFieldLabel = uilabel(app.ViewPanel);
            app.LowerXLimitEditFieldLabel.Text = 'Lower X Limit';
            app.LowerXLimitEditField = uieditfield(app.ViewPanel, 'text');
            app.UpperXLimitEditFieldLabel = uilabel(app.ViewPanel);
            app.UpperXLimitEditFieldLabel.Text = 'Upper X Limit';
            app.UpperXLimitEditField = uieditfield(app.ViewPanel, 'text');
            app.ShowFilteredECGCheckBox = uicheckbox(app.ViewPanel);
            app.ShowFilteredECGCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowFilteredECGCheckBoxValueChanged, true);
            app.ShowFilteredECGCheckBox.Enable = 'off';
            app.ShowFilteredECGCheckBox.Text = 'Show Filtered ECG';
            app.ShowFilteredECGCheckBox.Value = true;
            app.ShowRawECGCheckBox = uicheckbox(app.ViewPanel);
            app.ShowRawECGCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowRawECGCheckBoxValueChanged, true);
            app.ShowRawECGCheckBox.Enable = 'off';
            app.ShowRawECGCheckBox.Text = 'Show Raw ECG';
            app.FileDetailsPanel = uipanel(app.UIFigure);
            app.FileDetailsPanel.Title = 'File Details';
            app.FileNameLabel = uilabel(app.FileDetailsPanel);
            app.FileNameLabel.Text = 'File Name:';
            app.SamplingRateLabel = uilabel(app.FileDetailsPanel);
            app.SamplingRateLabel.Text = 'Sampling Rate:';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Resources', 'TF Logo.png');
            app.Ver08Label = uilabel(app.UIFigure);
            app.Ver08Label.Text = 'Ver. 0.8';
            app.DataRemovalPanel = uipanel(app.UIFigure);
            app.DataRemovalPanel.Title = 'Data Removal';
            app.EnterRemovalModeButton = uibutton(app.DataRemovalPanel, 'state');
            app.EnterRemovalModeButton.ValueChangedFcn = createCallbackFcn(app, @EnterRemovalModeButtonValueChanged, true);
            app.EnterRemovalModeButton.Text = 'Enter Removal Mode';
            app.SelectDataforRemovalButton = uibutton(app.DataRemovalPanel, 'push');
            app.SelectDataforRemovalButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDataforRemovalButtonPushed, true);
            app.SelectDataforRemovalButton.Enable = 'off';
            app.SelectDataforRemovalButton.Text = 'Select Data for Removal';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end